classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField         matlab.ui.control.EditField
        PlayTetrisButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        game = Game() % Description
        keyPressed = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayTetrisButton
        function PlayTetrisButtonPushed(app, event)
            %app.game = app.game.playGame();
            app.EditField.Value = 0;
            app.game = app.game.readHighScore();
            %set(gcf,'Visible','on');
            %spy(app.game.board.boardMatrix, 'k');
            %set(gcf,'currentch',char(1));
            value = 0;
            while app.game.play
           
                if (app.game.board.gameOver)
                    app.game.play = false;
                end
                %value = double(get(gcf,'CurrentCharacter'));
                
                switch app.keyPressed
                    case 28
                        app.game = app.game.moveOnKeyPress(value);
                    case 29
                        app.game = app.game.moveOnKeyPress(value);
                    case 30
                        app.game = app.game.moveOnKeyPress(value);
                    case 31
                        app.game = app.game.moveOnKeyPress(value);
                    case 32
                        pause;
                       figure(app.UIFigure);
                    case 1
                    otherwise
                        app.game.play = false;
                end
                    
                [i,j] = find(app.game.board.boardMatrix);
                [m,n] = size(app.game.board.boardMatrix);
                plot(app.UIAxes,j,i,'marker','.','markersize',6,'linestyle','none');
                xlabel(app.UIAxes,[strcat('Skore: ', "" + app.game.board.score + "." + app.game.fileText)]);set(app.UIAxes,'xlim',[0 n+1],'ylim',[0 m+1],'ydir','reverse','plotboxaspectratio',[n+1 m+1 1]);


                if (app.game.board.gameOver)
                    app.game.play = false;
                end
                
                app.game = app.game.moveDown();
            
                app.keyPressed = 1;
                
                pause(0.5);
                figure(app.UIFigure);
            end

            highscore = str2double(split(app.game.fileText));
            if (highscore(3) < app.game.board.score)
                app.game = app.game.writeHighScore();
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    app.keyPressed = 28;
                case 'rightarrow'
                    app.keyPressed = 29;
                case 'uparrow'
                    app.keyPressed = 30;
                case 'downarrow'
                    app.keyPressed = 31;
                case 'space'
                    app.keyPressed = 32;
            end
            app.EditField.Value = app.keyPressed;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlayTetrisButton = uibutton(app.UIFigure, 'push');
            app.PlayTetrisButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTetrisButtonPushed, true);
            app.PlayTetrisButton.Text = 'Play Tetris';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end