classdef tetrisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField         matlab.ui.control.EditField
        PlayTetrisButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        game = Game() % Description
        keyPressed = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayTetrisButton
        function PlayTetrisButtonPushed(app, event)
            app.EditField.Value = "0";
            app.game = app.game.readHighScore();

            while app.game.play
           
                if (app.game.board.gameOver)
                    app.game.play = false;
                end
                
                switch app.keyPressed
                    case 28
                        app.game = app.game.moveOnKeyPress(app.keyPressed);
                    case 29
                        app.game = app.game.moveOnKeyPress(app.keyPressed);
                    case 30
                        app.game = app.game.moveOnKeyPress(app.keyPressed);
                    case 31
                        app.game = app.game.moveOnKeyPress(app.keyPressed);
                    case 32
                        pause;
                       figure(app.UIFigure);
                    case 1
                    otherwise
                        app.game.play = false;
                end

                app.keyPressed = 1;
                    
                [i,j] = find(app.game.board.boardMatrix);
                [m,n] = size(app.game.board.boardMatrix);
                plot(app.UIAxes,j,i,'marker','.','markersize',15,'linestyle','none');
    
                xlabel(app.UIAxes,[strcat('Skore: ', "" + app.game.board.score + "." + app.game.fileText)]);set(app.UIAxes,'xlim',[0 n+1],'ylim',[0 m+1],'ydir','reverse','plotboxaspectratio',[n+1 m+1 1]);

                if (app.game.board.gameOver)
                    app.game.play = false;
                end
                
                app.game = app.game.moveDown();
                
                pause(0.5);
                figure(app.UIFigure);
            end

            highscore = str2double(split(app.game.fileText));
            if (highscore(3) < app.game.board.score)
                app.game = app.game.writeHighScore();
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    app.keyPressed = 28;
                case 'rightarrow'
                    app.keyPressed = 29;
                case 'uparrow'
                    app.keyPressed = 30;
                case 'downarrow'
                    app.keyPressed = 31;
                case 'space'
                    app.keyPressed = 32;
            end
            app.EditField.Value = "" + app.keyPressed;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Callback function
        function PlaycolorfulTetrisButtonPushed(app, event)
            app.game.play = false;

            app.game = app.game.playGame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tetris')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 20];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.UIAxes.YTickLabel = '';
            app.PlayTetrisButton = uibutton(app.UIFigure, 'push');
            app.PlayTetrisButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTetrisButtonPushed, true);
            app.PlayTetrisButton.Text = 'Play Tetris';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tetrisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end